classdef Deploy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TrainButton               matlab.ui.control.Button
        OverviewButton            matlab.ui.control.Button
        DeployLabel               matlab.ui.control.Label
        ConversiontoONNXPanel     matlab.ui.container.Panel
        ConvertButton             matlab.ui.control.Button
        ViewButton                matlab.ui.control.Button
        ListBox_1                 matlab.ui.control.ListBox
        ListoftrainedmodelLabel   matlab.ui.control.Label
        ActionLabel_1             matlab.ui.control.Label
        DeploytoMRheadsetPanel    matlab.ui.container.Panel
        Hyperlink                 matlab.ui.control.Hyperlink
        URLLabel                  matlab.ui.control.Label
        ListBox_2                 matlab.ui.control.ListBox
        RefreshButton             matlab.ui.control.Button
        ActionLabel_2             matlab.ui.control.Label
        ListofavailableONNXLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the path to the project JSON file
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            jsonFilePath = fullfile(project_dir, 'project.json');
            
            % Check if the project.json file exists
            if isfile(jsonFilePath)
                % Read the project.json file
                jsonData = fileread(jsonFilePath);
                projectInfo = jsondecode(jsonData);
                
                % Process trained_model field
                if isfield(projectInfo, 'Deploy') && isfield(projectInfo.Deploy, 'trained_model')
                    % Extract the trained models directly
                    trainedModels = projectInfo.Deploy.trained_model;
                    
                    % Handle empty array case and convert to cell array of strings
                    if isempty(trainedModels)
                        trainedModels = {}; % Set to an empty cell array
                    elseif ischar(trainedModels)
                        trainedModels = {trainedModels}; % Convert single string to cell array
                    elseif isstring(trainedModels)
                        trainedModels = cellstr(trainedModels); % Convert string array to cell array
                    end
                    
                    % Update the ListBox with trained model names
                    app.ListBox_1.Items = trainedModels;
                else
                    % If the field does not exist or is empty, set an empty list
                    app.ListBox_1.Items = {};
                end
            end

            % For the ONNX part, list all names of files in the 'onnx' folder in the project directory
            onnx_folder = fullfile(project_dir, 'onnx');
            
            % Check if the 'onnx' folder exists
            if isfolder(onnx_folder)
                % Get a list of .onnx files in the 'onnx' folder
                files = dir(fullfile(onnx_folder, '*.onnx'));
                
                % Get the names of the files
                onnx_files = {files.name};
                
                % If the list is empty, set to empty cell array
                if isempty(onnx_files)
                    app.ListBox_2.Items = {};
                else
                    app.ListBox_2.Items = onnx_files;
                end
            end
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            close(app.UIFigure);
            Overview();
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % Define the path to the project JSON file
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            jsonFilePath = fullfile(project_dir, 'project.json');
            
            % Check if the project.json file exists
            if isfile(jsonFilePath)
                % Read the project.json file
                jsonData = fileread(jsonFilePath);
                projectInfo = jsondecode(jsonData);
                
                % Process Train Configuration
                if isfield(projectInfo, 'Train') && isfield(projectInfo.Train, 'configuration')
                    configInfo = projectInfo.Train.configuration;
                    
                    % Process DatasetPreprocess and ModelSpecification configuration
                    if isfield(configInfo, 'DatasetPreprocess') && isfield(configInfo.DatasetPreprocess, 'dataset_name') && ...
                       isfield(configInfo, 'ModelSpecification') && isfield(configInfo.ModelSpecification, 'ModelName')
                        % Close the current figure and proceed to Train
                        close(app.UIFigure);
                        Train();
                    else
                        % Alert the user if dataset or model is not selected
                        uialert(app.UIFigure, 'Please select dataset and model before training.', 'Error');
                    end
                else
                    % Alert if the 'Train' or 'configuration' field doesn't exist
                    uialert(app.UIFigure, 'Train configuration is missing.', 'Error');
                end
            else
                % Alert the user if the project.json file doesn't exist
                uialert(app.UIFigure, 'Configuration file does not exist.', 'Error');
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            try
                % Get shared data and project directory
                data = SharedData.getInstance(); 
                project_dir = data.project_dir;
                trained_name = app.ListBox_1.Value;
                trained_file = strcat("trained_", trained_name, ".json");
                trained_dir = fullfile(project_dir, "trained_model", trained_file);
        
                % Check if the file exists
                if isfile(trained_dir)
                    % Read the existing JSON file content
                    fid = fopen(trained_dir, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
                    
                    % Decode the JSON text into a MATLAB structure
                    jsonData = jsondecode(rawJsonText);
        
                    % Convert the structure to a readable string format for display
                    jsonStr = jsonencode(jsonData, 'PrettyPrint', true);
        
                    % Display the content in a pop-up window (using a non-blocking dialog)
                    msgbox(jsonStr, 'Trained model Information', 'modal');
                else
                    % Show an error dialog if the file does not exist
                    uialert(app.UIFigure, sprintf('Trained model file not found:\n%s', trained_dir), 'File Not Found');
                end
            catch ME
                % Handle any errors that occur during file read or decoding
                uialert(app.UIFigure, sprintf('Error reading the JSON file: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            data = SharedData.getInstance(); 
            project_dir = data.project_dir;
            trained_name = app.ListBox_1.Value;
            
            % Construct the path to the trained model file
            trained_file = strcat("trained_", trained_name, ".mat");
            trained_dir = fullfile(project_dir, "trained_model", trained_file);
            
            % Load the trained network
            load(trained_dir, 'net');
            
            % Analyze the network (optional)
            analyzeNetwork(net);
            
            % Define the TensorFlow export directory and ensure it exists
            tf_folder = fullfile(project_dir, 'tf');
            if ~exist(tf_folder, 'dir')
                mkdir(tf_folder);
            end
            
            % Construct the full path for the TensorFlow model
            tf_file = fullfile(tf_folder, trained_name);
            
            % Export the network to TensorFlow format
            exportNetworkToTensorFlow(net, tf_file);

            % Copy 'model_conversion.py' from the 'mlapp' folder to the 'tf' folder if it does not exist
            % Get the path to the 'mlapp' folder (assuming it's the folder where this script is located)
            mlapp_folder = fileparts(mfilename('fullpath'));
            source_py_file = fullfile(mlapp_folder, 'model_conversion.py');
            destination_py_file = fullfile(tf_folder, 'model_conversion.py');
            if ~exist(destination_py_file, 'file')
                if exist(source_py_file, 'file')
                    copyfile(source_py_file, destination_py_file);
                else
                    error('Source Python file "model_conversion.py" does not exist in the mlapp folder.');
                end
            end
            
            % Create the 'onnx' folder in the project directory if it does not exist
            onnx_folder = fullfile(project_dir, 'onnx');
            if ~exist(onnx_folder, 'dir')
                mkdir(onnx_folder);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
             % Define the path to the project JSON file
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            % For the ONNX part, list all names of files in the 'onnx' folder in the project directory
            onnx_folder = fullfile(project_dir, 'onnx');
            
            % Check if the 'onnx' folder exists
            if isfolder(onnx_folder)
                % Get a list of .onnx files in the 'onnx' folder
                files = dir(fullfile(onnx_folder, '*.onnx'));
                
                % Get the names of the files
                onnx_files = {files.name};
                
                % If the list is empty, set to empty cell array
                if isempty(onnx_files)
                    app.ListBox_2.Items = {};
                else
                    app.ListBox_2.Items = onnx_files;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeploytoMRheadsetPanel = uipanel(app.UIFigure);
            app.DeploytoMRheadsetPanel.Title = 'Deploy to MR headset';
            app.ListofavailableONNXLabel = uilabel(app.DeploytoMRheadsetPanel);
            app.ListofavailableONNXLabel.Text = 'List of available ONNX:';
            app.ActionLabel_2 = uilabel(app.DeploytoMRheadsetPanel);
            app.ActionLabel_2.Text = 'Action:';
            app.RefreshButton = uibutton(app.DeploytoMRheadsetPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ListBox_2 = uilistbox(app.DeploytoMRheadsetPanel);
            app.ListBox_2.Items = {'', ''};
            app.ListBox_2.Value = {};
            app.URLLabel = uilabel(app.DeploytoMRheadsetPanel);
            app.URLLabel.Text = 'URL: ';
            app.Hyperlink = uihyperlink(app.DeploytoMRheadsetPanel);
            app.Hyperlink.Text = 'https://localhost:8080';
            app.ConversiontoONNXPanel = uipanel(app.UIFigure);
            app.ConversiontoONNXPanel.Title = 'Conversion to ONNX';
            app.ActionLabel_1 = uilabel(app.ConversiontoONNXPanel);
            app.ActionLabel_1.Text = 'Action:';
            app.ListoftrainedmodelLabel = uilabel(app.ConversiontoONNXPanel);
            app.ListoftrainedmodelLabel.Text = 'List of trained model:';
            app.ListBox_1 = uilistbox(app.ConversiontoONNXPanel);
            app.ListBox_1.Items = {''};
            app.ListBox_1.Value = {};
            app.ViewButton = uibutton(app.ConversiontoONNXPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.ConvertButton = uibutton(app.ConversiontoONNXPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.DeployLabel = uilabel(app.UIFigure);
            app.DeployLabel.Text = 'Deploy';
            app.OverviewButton = uibutton(app.UIFigure, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Icon = fullfile(pathToMLAPP, 'Overview.png');
            app.OverviewButton.Text = 'Overview';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Icon = fullfile(pathToMLAPP, 'Neural Network.png');
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deploy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end